classdef ESC_DPA_Coverage_rev2_9_9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadESCLocationDataButton     matlab.ui.control.StateButton
        TextArea                      matlab.ui.control.TextArea
        UITableLocation               matlab.ui.control.Table
        rev299Label                   matlab.ui.control.Label
        GenerateReportButton          matlab.ui.control.Button
        Step1Label                    matlab.ui.control.Label
        Step4Label                    matlab.ui.control.Label
        SampleStepSizeLabel           matlab.ui.control.Label
        SampleStepSizeEditField       matlab.ui.control.NumericEditField
        kmLabel                       matlab.ui.control.Label
        ESCDPAReportGeneratorLabel    matlab.ui.control.Label
        InputFileLocLabel             matlab.ui.control.Label
        ESC_Data_filename             matlab.ui.control.EditField
        LoadESCLogicDataButton        matlab.ui.control.StateButton
        Step2Label                    matlab.ui.control.Label
        InputFileLogLabel             matlab.ui.control.Label
        ESC_Logic_filename            matlab.ui.control.EditField
        ComputationSelectButtonGroup  matlab.ui.container.ButtonGroup
        ParallelButton                matlab.ui.control.ToggleButton
        SerialButton                  matlab.ui.control.ToggleButton
        UITableLogic                  matlab.ui.control.Table
        ForRandIdxLabel               matlab.ui.control.Label
        OutputButtonGroup             matlab.ui.container.ButtonGroup
        WordReportButton              matlab.ui.control.ToggleButton
        IndividualPlotsButton         matlab.ui.control.ToggleButton
        AntennaModelButtonGroup       matlab.ui.container.ButtonGroup
        GenericButton                 matlab.ui.control.ToggleButton
        CustomButton                  matlab.ui.control.ToggleButton
        Step3Label                    matlab.ui.control.Label
        LoadESCAntennaDataButton      matlab.ui.control.StateButton
        InputFileAntLabel             matlab.ui.control.Label
        ESC_Ant_filename              matlab.ui.control.EditField
        UITableAntPattern             matlab.ui.control.Table
        SubTextArea                   matlab.ui.control.TextArea
        ParpoolWorkersEditFieldLabel  matlab.ui.control.Label
        ParpoolWorkersEditField       matlab.ui.control.NumericEditField
        SelectFolderButton            matlab.ui.control.Button
    end


    methods (Access = private)
        
        function check_ant_select(app)
            if app.GenericButton.Value==1
                app.LoadESCAntennaDataButton.Visible='off';
                app.InputFileAntLabel.Visible='off';
                app.ESC_Ant_filename.Visible='off';
                app.UITableAntPattern.Visible='off';
                disp_progress(app,strcat('Ready to Generate the Report'))
                
                app.GenerateReportButton.Visible='on';
                app.Step4Label.Visible='on';
                app.GenerateReportButton.BackgroundColor='y';
                app.SampleStepSizeEditField.Visible='on';
                app.SampleStepSizeLabel.Visible='on';
                app.kmLabel.Visible='on';
                
            elseif app.CustomButton.Value==1
                %%%%%%%%%%Check if there is a value in the box
                ant_string=app.ESC_Ant_filename.Value
                app.LoadESCAntennaDataButton.Visible='on';
                app.LoadESCAntennaDataButton.BackgroundColor='y';
                
                if isempty(ant_string)==1
                    %%%%%%%Need to load a file
                    disp_progress(app,strcat('Load the ESC Antenna Data'))
                    app.GenerateReportButton.Visible='off';
                    app.Step4Label.Visible='off';
                    app.SampleStepSizeEditField.Visible='off';
                    app.SampleStepSizeLabel.Visible='off';
                    app.kmLabel.Visible='off';
                else
                    %%%%%Generate the Report
                    disp_progress(app,strcat('Ready to Generate the Report'))
                    app.LoadESCAntennaDataButton.BackgroundColor=[1,1,1];
                    app.LoadESCAntennaDataButton.FontColor=[0.5,0.5,0.5];
                    
                    app.UITableAntPattern.Visible='on';
                    app.InputFileAntLabel.Visible='on';
                    app.ESC_Ant_filename.Visible='on';
                    app.GenerateReportButton.Visible='on';
                    app.Step4Label.Visible='on';
                    app.GenerateReportButton.BackgroundColor='y';
                    app.SampleStepSizeEditField.Visible='on';
                    app.SampleStepSizeLabel.Visible='on';
                    app.kmLabel.Visible='on';
                end
            end
        end
    
        function check_comp(app)
            if app.ParallelButton.Value==1
                app.ParpoolWorkersEditField.Visible='on';
                app.ParpoolWorkersEditFieldLabel.Visible='on';
            elseif app.SerialButton.Value==1
                app.ParpoolWorkersEditField.Visible='off';
                app.ParpoolWorkersEditFieldLabel.Visible='off';
            end
        end
    
        
%         function [TerHandler,TerDirectory]=terrain_path(app)
%             terrain_string=app.TerrainDatabaseButtonGroup.SelectedObject.Text;
%             if strcmp(terrain_string,'3 arc-second')
%                 TerHandler=int32(1);
%                 TerDirectory = 'C:\USGS\';
%             elseif strcmp(terrain_string,'1 arc-second')==1
%                 TerHandler=int32(2);
%                 TerDirectory = 'C:\NED1\float';
%             end
%             app.TerrainPathEditField.Value=TerDirectory;            
%         end
        
        function sub_load_antenna_pattern(app,xls_file3)
            disp_progress(app,strcat('Loading Data . . .'))
            [num3,txt3,raw3]=xlsread(xls_file3);
            disp_progress(app,strcat('Loaded File . . .'))
            header3_varname=txt3(1,:);
            idx_degree=find(contains(header3_varname,'Degrees'));
            temp_degree=cell2mat(raw3(2:end,idx_degree));
            temp_degree=temp_degree(~isnan(temp_degree));
            idx_antennas=2:1:length(header3_varname);
            ant_names_header=header3_varname(idx_antennas);
            x7=length(idx_antennas);
            cell_custom_ant=cell(x7,1);
            for i=1:1:x7
                temp_ant_gain_array=cell2mat(raw3(2:end,idx_antennas(i)));
                temp_ant_gain_array=temp_ant_gain_array(~isnan(temp_ant_gain_array));
                ant_gain_dual_rows=horzcat(temp_degree,temp_ant_gain_array);
                idx_zero=find(ant_gain_dual_rows(:,1)==0);
                cell_custom_ant{i}=circshift(ant_gain_dual_rows,360-(idx_zero-1));
            end
            
            x13=length(temp_degree);
            cell_gain_dual_rows=cell(x13,x7); %%%%Lat,Lon,AntHeight,Azi,BW,Gain
            for j=1:1:x7
                ant_gain_dual_rows=cell_custom_ant{j};
                for i=1:1:x13
                    if j==1
                        cell_gain_dual_rows{i,j}=horzcat(ant_gain_dual_rows(i,:));
                    else
                        cell_gain_dual_rows{i,j}=horzcat(ant_gain_dual_rows(i,2));
                    end
                end
            end
            
            %%%%%%Convert to individual cells for table
            cell_num=num2cell(cell2mat(cell_gain_dual_rows));
            table1=cell_num;
            app.ESC_Ant_filename.Value=xls_file3;
            
            app.UITableAntPattern.Data=table1;
            app.UITableAntPattern.Visible='on';
            
            disp_progress(app,strcat('Ready to Generate the Report'))

            app.LoadESCAntennaDataButton.BackgroundColor=[1,1,1];
            app.LoadESCAntennaDataButton.FontColor=[0.5,0.5,0.5];
            
            app.InputFileAntLabel.Visible='on';
            app.ESC_Ant_filename.Visible='on';
            app.GenerateReportButton.Visible='on';
            app.Step4Label.Visible='on';
            app.GenerateReportButton.BackgroundColor='y';
            app.SampleStepSizeEditField.Visible='on';
            app.SampleStepSizeLabel.Visible='on';
            app.kmLabel.Visible='on';
        end
        
        function sub_load_location(app,xls_file)
            disp_progress(app,strcat('Loading Data . . .'))
            [num,txt,raw]=xlsread(xls_file);
            disp_progress(app,strcat('Loaded Data . . .'))
            header_varname=txt(1,:);
            
            %%%%%%%%%If the table gets new columns, find the header names assign them dynamically
            idx_lat=find(contains(header_varname,'Latitude'));
            idx_lon=find(contains(header_varname,'Longitude'));
            idx_ant_height=find(contains(header_varname,'ESC Antenna Height'));
            idx_ant_azi=find(contains(header_varname,'ESC Antenna Azimuth'));
            idx_ant_bw=find(contains(header_varname,'Antenna Beamwidth'));
            idx_ant_gain=find(contains(header_varname,'Antenna Gain'));
            idx_site_name=find(contains(header_varname,'Site Name'));
            idx_cable_loss=find(contains(header_varname,'Cable Loss'));
            idx_ant_name=find(contains(header_varname,'Antenna Name'));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            temp_lat=cell2mat(raw(2:end,idx_lat));
            temp_lat=temp_lat(~isnan(temp_lat));
            temp_lon=cell2mat(raw(2:end,idx_lon));
            temp_lon=temp_lon(~isnan(temp_lon));
            temp_ant_height=cell2mat(raw(2:end,idx_ant_height));
            temp_ant_height=temp_ant_height(~isnan(temp_ant_height));
            temp_ant_azi=cell2mat(raw(2:end,idx_ant_azi));
            temp_ant_azi=temp_ant_azi(~isnan(temp_ant_azi));
            temp_ant_bw=cell2mat(raw(2:end,idx_ant_bw));
            temp_ant_bw=temp_ant_bw(~isnan(temp_ant_bw));
            temp_ant_gain_dB=cell2mat(raw(2:end,idx_ant_gain));
            temp_ant_gain_dB=temp_ant_gain_dB(~isnan(temp_ant_gain_dB));
            temp_site_name=raw(2:(length(temp_lat)+1),idx_site_name);
            temp_site_name=cellfun(@num2str,temp_site_name,'un',0); %%%Just make sure it is a string
            temp_cable_loss=cell2mat(raw(2:end,idx_cable_loss));
            temp_cable_loss=temp_cable_loss(~isnan(temp_cable_loss));
            temp_ant_name=raw(2:(length(temp_lat)+1),idx_ant_name);
            temp_ant_name=cellfun(@num2str,temp_ant_name,'un',0); %%%Just make sure it is a string
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%Check to see if you have the adequate info for each ESC.
            %%%%%%%Check the size of the inputs, needs to be the same length
            tf_esc_check1=isequal(length(temp_lat),length(temp_lon),length(temp_ant_height),length(temp_ant_azi),length(temp_ant_bw),length(temp_ant_gain_dB),length(temp_site_name),length(temp_cable_loss));
            
            %%%%%Also check to see if there are any empty slots, which will be NaNs
            tf_esc_check2=any(isnan(vertcat(temp_lat,temp_lon,temp_ant_height,temp_ant_azi,temp_ant_bw,temp_ant_gain_dB,temp_cable_loss)))==0;
            
            
            if tf_esc_check1==0 || tf_esc_check2==0
                disp_progress(app,strcat('Incomplete ESC Location Inputs'))
            else %%%%Fill Out the Table
                x13=length(temp_site_name);
                cell_esc_stats=cell(x13,1); %%%%Lat,Lon,AntHeight,Azi,BW,Gain
                for i=1:1:x13
                    cell_esc_stats{i}=horzcat(temp_lat(i),temp_lon(i),temp_ant_height(i),temp_ant_azi(i),temp_ant_bw(i),temp_ant_gain_dB(i),temp_cable_loss(i));
                end                    
                    
                %%%%%%Convert to individual cells for table
                cell_num=num2cell(cell2mat(cell_esc_stats))
                table1=horzcat(temp_site_name,cell_num,temp_ant_name);
                
                app.UITableLocation.Data=table1;
                disp_progress(app,strcat('Load ESC Logic Data'))
                app.LoadESCLogicDataButton.Visible='on';
                app.LoadESCLogicDataButton.BackgroundColor='y';
                app.Step2Label.Visible='on';
                app.UITableLocation.Visible='on';
                app.LoadESCLocationDataButton.BackgroundColor=[1,1,1];
                app.LoadESCLocationDataButton.FontColor=[0.5,0.5,0.5];
                app.ESC_Data_filename.Visible='on';
                app.InputFileLocLabel.Visible='on';
                app.ESC_Data_filename.Value=xls_file;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%
        end
        
        function sub_load_logic(app,xls_file2)
            disp_progress(app,strcat('Loading Data . . .'))            
            [num2,txt2,raw2]=xlsread(xls_file2);
            disp_progress(app,strcat('Loaded Data . . .'))
            header2_varname=txt2(1,:);
            idx_dpa=find(contains(header2_varname,'DPA Covered'));
            temp_dpa=raw2(2:end,idx_dpa);
            logic_row=cell(length(temp_dpa),1);
            temp_max_length=0;
            for i=1:1:length(temp_dpa)
                temp_row=raw2(i+1,:);
                temp_row(cellfun(@(temp_row) any(isnan(temp_row)),temp_row))=[];
                logic_row{i}=cellfun(@num2str,temp_row,'un',0); %%%Just make sure it is a string
                temp_logic_cell=logic_row(i);
                x32=length(temp_logic_cell);
                for k=1:1:x32
                    temp_max_length=max([temp_max_length,length(temp_logic_cell{k})]);
                end
            end
            
            expand_temp_logic_cell=logic_row;
            for i=1:1:length(logic_row)
                temp_cell_len=length(logic_row{i});
                if temp_cell_len<temp_max_length
                    temp_add_cell=temp_max_length-temp_cell_len;
                    temp_space_cells=cell(1,temp_add_cell);
                    temp_space_cells(:)={' '};
                    expand_temp_logic_cell{i}=horzcat(logic_row{i},temp_space_cells);
                end
            end
            
            %%%%Unpack
            table2=horzcat(vertcat(expand_temp_logic_cell{:}));
            app.UITableLogic.Data=table2;
            app.UITableLogic.Visible='on';
            
            app.AntennaModelButtonGroup.Visible='on';
            app.Step3Label.Visible='on';
            check_ant_select(app)
            
            app.LoadESCLogicDataButton.BackgroundColor=[1,1,1];
            app.LoadESCLogicDataButton.FontColor=[0.5,0.5,0.5];
            
            app.InputFileLogLabel.Visible='on';
            app.ESC_Logic_filename.Visible='on';
            app.ESC_Logic_filename.Value=xls_file2;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            check_comp(app)
            %%%%%%%%%To be safe, the number of Workers based off of 2GB rule.
            [workers,max_cores]=recommend_num_workers(app);
            app.ParpoolWorkersEditField.Limits=[1 max_cores];
            app.ParpoolWorkersEditField.Value=workers;
            
            app.TextArea.Value={'Load the ESC Location Data'};
            app.LoadESCLocationDataButton.BackgroundColor='y';
            app.LoadESCLogicDataButton.Visible='off';
            app.GenerateReportButton.Visible='off';
            app.Step2Label.Visible='off';
            app.Step4Label.Visible='off';
            app.UITableLocation.Visible='off';
            app.UITableLogic.Visible='off';
            app.SampleStepSizeEditField.Visible='off';
            app.SampleStepSizeLabel.Visible='off';
            app.kmLabel.Visible='off';
            app.ESC_Logic_filename.Visible='off';
            app.InputFileLogLabel.Visible='off';
            app.ESC_Data_filename.Visible='off';
            app.InputFileLocLabel.Visible='off';
            
            app.AntennaModelButtonGroup.Visible='off';
            app.Step3Label.Visible='off';
            app.LoadESCAntennaDataButton.Visible='off';
            app.InputFileAntLabel.Visible='off';
            app.ESC_Ant_filename.Visible='off';
            app.UITableAntPattern.Visible='off';

        end

        % Value changed function: LoadESCLocationDataButton
        function LoadESCLocationDataButtonValueChanged(app, event)
            [xls_file,path]=uigetfile('*.xlsx');
            sub_load_location(app,xls_file)
            
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            app.GenerateReportButton.Visible='off';
            disp_progress(app,strcat('Starting the ESC-DPA Calculations'))
            
            app.GenerateReportButton.BackgroundColor=[1,1,1];
            app.GenerateReportButton.FontColor=[0.5,0.5,0.5];
            step_size=app.SampleStepSizeEditField.Value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Parallel
            if app.ParallelButton.Value==1
                parallel_flag=1; %0 --> serial, 1 --> parallel
                workers=app.ParpoolWorkersEditField.Value;
                [poolobj,cores]=start_parpool_poolsize_app(app,parallel_flag,workers);
            elseif app.SerialButton.Value==1
                parallel_flag=0;
                workers=1;
                [poolobj,cores]=start_parpool_poolsize_app(app,parallel_flag,workers);
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.WordReportButton.Value==1
                write_report=1;
            elseif app.IndividualPlotsButton.Value==1
                write_report=0;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if app.GenericButton.Value==1
                tf_custom_ant=0;
            elseif app.CustomButton.Value==1
                tf_custom_ant=1;
            end
            
            
            %%%%%%%%%%%%%Terrain Database
            TerHandler=int32(1)
            if TerHandler==2
                TerDirectory = 'P:\NED1\float';
            elseif TerHandler==1
                TerDirectory = 'C:\USGS\';
            end
            
            
            %%%%%%%%Reload the xls as it seems to be the easier way
            filename1=app.ESC_Data_filename.Value;
            filename2=app.ESC_Logic_filename.Value;
            filename3=app.ESC_Ant_filename.Value;
            tf_load=1;
            industry_label='XXX';
            
            %%%%%%%%esc_analysis_rev6_hawaii_pr_app(app,step_size,filename1,filename2,filename3,parallel_flag,write_report,tf_custom_ant,tf_load,industry_label,TerHandler,TerDirectory,workers)
            esc_analysis_rev7_parfor_rand_app(app,step_size,filename1,filename2,filename3,parallel_flag,write_report,tf_custom_ant,tf_load,industry_label,TerHandler,TerDirectory,workers)
            disp_progress(app,strcat('Report Done'))
            app.GenerateReportButton.Visible='on';
            
        end

        % Value changed function: LoadESCLogicDataButton
        function LoadESCLogicDataButtonValueChanged(app, event)
            [xls_file2,path]=uigetfile('*.xlsx');
            sub_load_logic(app,xls_file2)
        end

        % Selection changed function: AntennaModelButtonGroup
        function AntennaModelButtonGroupSelectionChanged(app, event)
            check_ant_select(app)
        end

        % Value changed function: LoadESCAntennaDataButton
        function LoadESCAntennaDataButtonValueChanged(app, event)
            [xls_file3,path]=uigetfile('*.xlsx');
            sub_load_antenna_pattern(app,xls_file3)
            
        end

        % Callback function
        function TerrainDatabaseButtonGroupSelectionChanged(app, event)
            %terrain_path(app)
        end

        % Selection changed function: ComputationSelectButtonGroup
        function ComputationSelectButtonGroupSelectionChanged(app, event)
            check_comp(app)
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            rev_folder=uigetdir('Z:\MATLAB\3.5GHz\DPA Coverage Analysis');
            cd(rev_folder)
            
            temp_sub_files=dir;
            x3=length(temp_sub_files);
            temp_filenames=cell(x3,1);
            for i=1:1:x3
                temp_filenames{i}=temp_sub_files(i).name;
            end
            idx_location=find(contains(temp_filenames,strcat('ESC_Location_Inputs')));
            idx_logic=find(contains(temp_filenames,strcat('ESC_Combo_Logic_Inputs')));
            idx_pattern=find(contains(temp_filenames,strcat('ESC_Antenna_Gain_Pattern')));
            
            if isempty(idx_location)==1 || length(idx_location)>1
                disp_progress(app,strcat('File Error Location Inputs [Pause]'))
                pause;
            end
            
            if isempty(idx_logic)==1 || length(idx_logic)>1
                disp_progress(app,strcat('File Error Logic Inputs [Pause]'))
                pause;
            end
            
            if isempty(idx_pattern)==1 || length(idx_pattern)>1
                disp_progress(app,strcat('File Error Antenna Pattern Input [Pause]'))
                pause;
            end
            
            filename1=temp_filenames{idx_location}
            filename2=temp_filenames{idx_logic}
            filename3=temp_filenames{idx_pattern}
            
            app.ESC_Data_filename.Value=filename1;
            app.ESC_Logic_filename.Value=filename2;
            app.ESC_Ant_filename.Value=filename3;
            
            app.ESC_Data_filename.Visible='on';
            app.InputFileLocLabel.Visible='on';
            
            app.InputFileLogLabel.Visible='on';
            app.ESC_Logic_filename.Visible='on';
            
            app.InputFileAntLabel.Visible='on';
            app.ESC_Ant_filename.Visible='on';
            app.GenerateReportButton.Visible='on';
            app.Step4Label.Visible='on';
            app.GenerateReportButton.BackgroundColor='y';
            app.SampleStepSizeEditField.Visible='on';
            app.SampleStepSizeLabel.Visible='on';
            app.kmLabel.Visible='on';
           
            sub_load_location(app,filename1)
            sub_load_logic(app,filename2)
            sub_load_antenna_pattern(app,filename3)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadESCLocationDataButton = uibutton(app.UIFigure, 'state');
            app.LoadESCLocationDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadESCLocationDataButtonValueChanged, true);
            app.LoadESCLocationDataButton.Text = 'Load ESC Location Data';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.UITableLocation = uitable(app.UIFigure);
            app.UITableLocation.ColumnName = {'Name'; 'Latitude'; 'Longitude'; 'ESC Antenna Height'; 'ESC Antenna Azimuth'; 'Antenna Beamwidth (°)'; 'Antenna Gain (dBi)'; 'Cable Loss (dB)'; 'Antenna Name'};
            app.UITableLocation.RowName = {};
            app.UITableLocation.ColumnEditable = [false true true true true true true];
            app.rev299Label = uilabel(app.UIFigure);
            app.rev299Label.Text = 'rev 2.9.9';
            app.GenerateReportButton = uibutton(app.UIFigure, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step 1:';
            app.Step4Label = uilabel(app.UIFigure);
            app.Step4Label.Text = 'Step 4: ';
            app.SampleStepSizeLabel = uilabel(app.UIFigure);
            app.SampleStepSizeLabel.Text = 'Sample Step Size:';
            app.SampleStepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleStepSizeEditField.Limits = [1 50];
            app.SampleStepSizeEditField.ValueDisplayFormat = '%.0f';
            app.SampleStepSizeEditField.Value = 2;
            app.kmLabel = uilabel(app.UIFigure);
            app.kmLabel.Text = 'km';
            app.ESCDPAReportGeneratorLabel = uilabel(app.UIFigure);
            app.ESCDPAReportGeneratorLabel.Text = 'ESC-DPA Report Generator';
            app.InputFileLocLabel = uilabel(app.UIFigure);
            app.InputFileLocLabel.Text = 'ESC Location File:';
            app.ESC_Data_filename = uieditfield(app.UIFigure, 'text');
            app.ESC_Data_filename.Editable = 'off';
            app.LoadESCLogicDataButton = uibutton(app.UIFigure, 'state');
            app.LoadESCLogicDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadESCLogicDataButtonValueChanged, true);
            app.LoadESCLogicDataButton.Text = 'Load ESC Logic Data';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step 2:';
            app.InputFileLogLabel = uilabel(app.UIFigure);
            app.InputFileLogLabel.Text = 'ESC Logic File:';
            app.ESC_Logic_filename = uieditfield(app.UIFigure, 'text');
            app.ESC_Logic_filename.Editable = 'off';
            app.ComputationSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.ComputationSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComputationSelectButtonGroupSelectionChanged, true);
            app.ComputationSelectButtonGroup.Title = 'Computation Select';
            app.ParallelButton = uitogglebutton(app.ComputationSelectButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.ParallelButton.Value = true;
            app.SerialButton = uitogglebutton(app.ComputationSelectButtonGroup);
            app.SerialButton.Text = 'Serial';
            app.UITableLogic = uitable(app.UIFigure);
            app.UITableLogic.ColumnName = {'DPA'; 'Site Name 1'; 'Site Name 2'; 'Site Name 3'; 'Site Name 4'; 'Site Name 5'};
            app.UITableLogic.RowName = {};
            app.ForRandIdxLabel = uilabel(app.UIFigure);
            app.ForRandIdxLabel.Text = 'For Rand Idx';
            app.OutputButtonGroup = uibuttongroup(app.UIFigure);
            app.OutputButtonGroup.Title = 'Output';
            app.WordReportButton = uitogglebutton(app.OutputButtonGroup);
            app.WordReportButton.Text = 'Word Report';
            app.IndividualPlotsButton = uitogglebutton(app.OutputButtonGroup);
            app.IndividualPlotsButton.Text = 'Individual Plots';
            app.IndividualPlotsButton.Value = true;
            app.AntennaModelButtonGroup = uibuttongroup(app.UIFigure);
            app.AntennaModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AntennaModelButtonGroupSelectionChanged, true);
            app.AntennaModelButtonGroup.Title = 'Antenna Model';
            app.GenericButton = uitogglebutton(app.AntennaModelButtonGroup);
            app.GenericButton.Text = 'Generic';
            app.CustomButton = uitogglebutton(app.AntennaModelButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.CustomButton.Value = true;
            app.Step3Label = uilabel(app.UIFigure);
            app.Step3Label.Text = 'Step 3: ';
            app.LoadESCAntennaDataButton = uibutton(app.UIFigure, 'state');
            app.LoadESCAntennaDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadESCAntennaDataButtonValueChanged, true);
            app.LoadESCAntennaDataButton.Text = 'Load ESC Antenna Data';
            app.InputFileAntLabel = uilabel(app.UIFigure);
            app.InputFileAntLabel.Text = 'ESC Ant File:';
            app.ESC_Ant_filename = uieditfield(app.UIFigure, 'text');
            app.ESC_Ant_filename.Editable = 'off';
            app.UITableAntPattern = uitable(app.UIFigure);
            app.UITableAntPattern.ColumnName = {'Degree'; 'Ant 1'; 'Ant 2'};
            app.UITableAntPattern.RowName = {};
            app.SubTextArea = uitextarea(app.UIFigure);
            app.ParpoolWorkersEditFieldLabel = uilabel(app.UIFigure);
            app.ParpoolWorkersEditFieldLabel.Text = 'Workers';
            app.ParpoolWorkersEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParpoolWorkersEditField.Limits = [1 24];
            app.ParpoolWorkersEditField.RoundFractionalValues = 'on';
            app.ParpoolWorkersEditField.Value = 12;
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESC_DPA_Coverage_rev2_9_9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end